classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ColumnsEditFieldLabel  matlab.ui.control.Label
        ColumnsEditField       matlab.ui.control.NumericEditField
        RowsEditFieldLabel     matlab.ui.control.Label
        RowsEditField          matlab.ui.control.NumericEditField
        CreatetabButton        matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        StepsEditFieldLabel    matlab.ui.control.Label
        StepsEditField         matlab.ui.control.NumericEditField
        StartButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        rows = 20;
        columns = 20;
        steps=20;
        tab;
    end
    
    methods (Access = private)
        
        function results = draw(app, tab)
            I = imshow(tab, 'Parent', app.UIAxes);

            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
        end
    end
    

    methods (Access = private)

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.columns = app.ColumnsEditField.Value;
            
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            app.rows = app.RowsEditField.Value;
            
        end

        % Button pushed function: CreatetabButton
        function tabButton(app, event)
            
            app.tab = generateRandTab(app.rows, app.columns);
            app.draw(app.tab);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.tab = generateRandTab(app.rows, app.columns);
            app.draw(app.tab);
            while app.steps > 0
                pause(1);
                new_bound = periodicBounds(app.tab);
                app.tab = nextStepTab(new_bound);
                app.draw(app.tab);
                app.steps = app.steps-1;
            end
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            app.steps = app.StepsEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.CreatetabButton = uibutton(app.UIFigure, 'push');
            app.CreatetabButton.ButtonPushedFcn = createCallbackFcn(app, @tabButton, true);
            app.CreatetabButton.Text = {'Create tab!'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StepsEditFieldLabel = uilabel(app.UIFigure);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end